classdef Respiration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        InitializeTab            matlab.ui.container.Tab
        Label                    matlab.ui.control.Label
        AnnouncementLabel        matlab.ui.control.Label
        SaveButton               matlab.ui.control.Button
        RecordButton             matlab.ui.control.StateButton
        FileNameEditField        matlab.ui.control.EditField
        FileNameEditFieldLabel   matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        ActivityModeButtonGroup  matlab.ui.container.ButtonGroup
        Activity2Button          matlab.ui.control.ToggleButton
        Activity1Button          matlab.ui.control.ToggleButton
        CalibrationButton        matlab.ui.control.ToggleButton
        SensorReadingsButton     matlab.ui.control.ToggleButton
        InitializeSensorsButton  matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        SignInTab                matlab.ui.container.Tab
        UserNameDropDown         matlab.ui.control.DropDown
        UserNameDropDownLabel    matlab.ui.control.Label
        SignInButton             matlab.ui.control.Button
        CalibrationTab           matlab.ui.container.Tab
        Activity1Tab             matlab.ui.container.Tab
        InstructionsField        matlab.ui.control.Label
        PlayTutorialButton       matlab.ui.control.Button
        DiscardScoreButton       matlab.ui.control.Button
        StopButton_2             matlab.ui.control.Button
        CompleteLabel            matlab.ui.control.Label
        StatusLabel              matlab.ui.control.Label
        TimestampFieldLabel      matlab.ui.control.Label
        TimestampLabel           matlab.ui.control.Label
        UsernameFieldLabel       matlab.ui.control.Label
        UsernameLabel            matlab.ui.control.Label
        countNum                 matlab.ui.control.Label
        CountLabel               matlab.ui.control.Label
        TimeNum                  matlab.ui.control.Label
        TimeLabel                matlab.ui.control.Label
        SaveScoreButton          matlab.ui.control.Button
        PauseButton              matlab.ui.control.Button
        StartButton_2            matlab.ui.control.Button
        Activity1Complete5diaphragmaticbreathsLabel  matlab.ui.control.Label
        UIAxes_A1                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        d %DAQ Interface
        dInfo %DAQ info
        fname; %file to store data
        recorded; %saved data
        calibration; %calibration matrix
        threshold; %peak threshold

    end
    
    methods (Access = private)
        
        function plotAcc(app, src, evt)
            [data, ts, ~]=read(src, src.ScansAvailableFcnCount,"OutputFormat","Matrix");
         
            yyaxis(app.UIAxes2,'left');
            plot(app.UIAxes2, ts, data,'Marker','none');
       
            app.recorded=[app.recorded; ts data];


        end

        
        function growCircle(app,thr_circle)
            
            hold(app.UIAxes_A1,'on');
            inc=[.75:0.01:2.25];
            d_color=1/length(inc);
            rgb_colors=[1:-d_color:d_color; d_color:d_color:1; zeros(1,length(inc))];
            t_pause=exp(linspace(log(0.00005), log(0.3),length(inc)));
            for i=1:length(inc)
                axis(app.UIAxes_A1,[0 6 0 6]);

                if(thr_circle)
                    viscircles(app.UIAxes_A1,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
                    if(i==find(inc==1.9)-1)
                        app.countNum.Text=num2str(1);
                    end
                end
                
                viscircles(app.UIAxes_A1,[3 3],inc(i),'Color',rgb_colors(:,i));
                
                hold(app.UIAxes_A1,'off');
                pause(t_pause(i));
            end
        end

        function shrinkCircle(app,thr_circle)
          
            hold(app.UIAxes_A1,'on');
            inc=[2.25:-0.01:.75];
            d_color=1/length(inc);
            rgb_colors=[d_color:d_color:1; 1:-d_color:d_color; zeros(1,length(inc))];
            t_pause=exp(linspace(log(0.0000001), log(0.0001),length(inc)));
            for i=1:length(inc)
                axis(app.UIAxes_A1,[0 6 0 6]);

                if(thr_circle)
                    viscircles(app.UIAxes_A1,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
                end
                
                viscircles(app.UIAxes_A1,[3 3],inc(i),'Color',rgb_colors(:,i));
                
                hold(app.UIAxes_A1,'off');
                pause(t_pause(i));
            end
        end

        function grow_shrinkCircle(app)
           
            inc=[2.25:-0.01:.75];
            d_color=1/length(inc);
            rgb_colors=[d_color:d_color:1; 1:-d_color:d_color; zeros(1,length(inc))];

            viscircles(app.UIAxes_A1,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
            pause(3);
            hold(app.UIAxes_A1,'on');
            growCircle(app,1);
            shrinkCircle(app,1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function preLoad(app)

            % yyaxis(app.UIAxes,'left');
            % ylabel(app.UIAxes, 'X,Y Acc(G)');

        end

        % Button pushed function: InitializeSensorsButton
        function initDAQ(app, event)
            mydaq=daq("adi"); %create DAQ Object called mydaq
            addinput(mydaq,'smu1','a','Voltage'); %Add chA as the input
            % addinput(mydaq,'smu1','b','Voltage'); %Add chB as the input
            mydaq.ScansAvailableFcnCount=0.5*mydaq.Rate; %set samples aquired to 0.5s
            app.d=mydaq; %store DAQ object to app property d
            app.dInfo=mydaq.Channels.Device; %store DAQ properties to app property dInfo
          
            disp('DAQ initialized');
        end

        % Button pushed function: StartButton
        function startDAQ(app, event)
            %call plotTemp when enough scans are available
            %Aqcuiring and plotting
            % create callback function to call plotPPG every time 2s scans available
            app.d.ScansAvailableFcn = @(src,evt) plotAcc(app,src,evt);    
            % start acquiring and plotting
            start(app.d, "continuous");

            disp('DAQ started');

        end

        % Button pushed function: StopButton
        function stopDAQ(app, event)
            %stop DAQ
            %Clear DAQ buffer and axes

            stop(app.d);                      %stop daq
            flush(app.d);                     %clear data in daq buffer
            cla(app.UIAxes);
            disp('DAQ stopped');
        end

        % Value changed function: FileNameEditField
        function changeName(app, event)
            value = app.FileNameEditField.Value;
        end

        % Button pushed function: SaveButton
        function saveData(app, event)
            data=app.recorded;
            save(app.FileNameEditField.Value,'data');
        end

        % Value changed function: RecordButton
        function startRecord(app, event)
            value = app.RecordButton.Value;
            app.recorded=[];
        end

        % Callback function: not associated with a component
        function addCircle(app, event)
            viscircles(app.UIAxes_A1,[3 3],2,'Color','g','LineStyle','--');
            hold(app.UIAxes_A1,'on');
            inc=[0.1:0.01:2.5];
            for i=1:length(inc)
                %cla(app.UIAxes_A1);
                axis(app.UIAxes_A1,[0 6 0 6]);
                viscircles(app.UIAxes_A1,[3 3],inc(i));
                viscircles(app.UIAxes_A1,[3 3],2,'Color','g','LineStyle','--');
                hold(app.UIAxes_A1,'off');
                pause(0.01);
            end
        end

        % Button down function: Activity1Tab
        function initActivity1(app, event)
            cla(app.UIAxes_A1);
            axis(app.UIAxes_A1,[0 6 0 6]);
            % viscircles(app.UIAxes_A1,[3 3],2,'Color','g','LineStyle','--');
            hold(app.UIAxes_A1,'on');
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            
        end

        % Button pushed function: PlayTutorialButton
        function startTutorial(app, event)
            %The red circle represents your breathing
            app.CountLabel.Text='';
            app.countNum.Text='';
            app.TimeLabel.Text='';
            app.TimeNum.Text='';
            app.InstructionsField.Text='The red circle represents your breathing.';
            viscircles(app.UIAxes_A1,[3 3],.75,'Color','r');
            pause(3);
            %When you breathe in, the circle will grow
            app.InstructionsField.Text='When you breathe in, the circle will grow.';
            pause(3);
            growCircle(app,0);
           
            %When you breathe out, the circle will shrink
            app.InstructionsField.Text='When you breathe out, the circle will shrink.';
            pause(3);
            shrinkCircle(app,0);
            
            %The dashed green circle is your goal. Every breath that
            %crosses this threshold will be counted as one breath.
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.InstructionsField.Text='The dashed green circle is your goal. Every inhale that crosses this threshold will be counted as one breath.';
            

            grow_shrinkCircle(app);

            pause(1);
            %You have 2mins to reach 5 successful diaphragmatic breaths

            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            
            app.InstructionsField.Text='You have 2mins to finish 5 successful diaphragmatic breaths.';
            pause(5);
            
            cla(app.UIAxes_A1);
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            app.InstructionsField.Text='';
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializeTab = uitab(app.TabGroup);
            app.InitializeTab.Title = 'Initialize';
            app.UIAxes = uiaxes(app.InitializeTab);
            title(app.UIAxes, 'Sensor Readings')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Breathing (AU)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.InitializeTab);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'PPG')
            zlabel(app.UIAxes2, 'Z')
            app.InitializeSensorsButton = uibutton(app.InitializeTab, 'push');
            app.InitializeSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @initDAQ, true);
            app.InitializeSensorsButton.Text = 'Initialize Sensors';
            app.ActivityModeButtonGroup = uibuttongroup(app.InitializeTab);
            app.ActivityModeButtonGroup.Title = 'Activity Mode';
            app.SensorReadingsButton = uitogglebutton(app.ActivityModeButtonGroup);
            app.SensorReadingsButton.Text = 'Sensor Readings';
            app.SensorReadingsButton.Value = true;
            app.CalibrationButton = uitogglebutton(app.ActivityModeButtonGroup);
            app.CalibrationButton.Text = 'Calibration';
            app.Activity1Button = uitogglebutton(app.ActivityModeButtonGroup);
            app.Activity1Button.Text = 'Activity 1';
            app.Activity2Button = uitogglebutton(app.ActivityModeButtonGroup);
            app.Activity2Button.Text = 'Activity 2';
            app.StartButton = uibutton(app.InitializeTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startDAQ, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.InitializeTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopDAQ, true);
            app.StopButton.Text = 'Stop';
            app.FileNameEditFieldLabel = uilabel(app.InitializeTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.InitializeTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @changeName, true);
            app.FileNameEditField.Value = 'dataName';
            app.RecordButton = uibutton(app.InitializeTab, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @startRecord, true);
            app.RecordButton.Text = 'Record';
            app.SaveButton = uibutton(app.InitializeTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveData, true);
            app.SaveButton.Text = 'Save';
            app.AnnouncementLabel = uilabel(app.InitializeTab);
            app.AnnouncementLabel.Text = 'Announcement:';
            app.Label = uilabel(app.InitializeTab);
            app.SignInTab = uitab(app.TabGroup);
            app.SignInTab.Title = 'Sign In';
            app.SignInButton = uibutton(app.SignInTab, 'push');
            app.SignInButton.Text = 'Sign In';
            app.UserNameDropDownLabel = uilabel(app.SignInTab);
            app.UserNameDropDownLabel.Text = 'UserName';
            app.UserNameDropDown = uidropdown(app.SignInTab);
            app.UserNameDropDown.Editable = 'on';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.Activity1Tab = uitab(app.TabGroup);
            app.Activity1Tab.Title = 'Activity 1';
            app.Activity1Tab.ButtonDownFcn = createCallbackFcn(app, @initActivity1, true);
            app.UIAxes_A1 = uiaxes(app.Activity1Tab);
            app.UIAxes_A1.XTick = [];
            app.UIAxes_A1.YTick = [];
            app.UIAxes_A1.ZTick = [];
            app.Activity1Complete5diaphragmaticbreathsLabel = uilabel(app.Activity1Tab);
            app.Activity1Complete5diaphragmaticbreathsLabel.Text = 'Activity 1: Complete 5 diaphragmatic breaths';
            app.StartButton_2 = uibutton(app.Activity1Tab, 'push');
            app.StartButton_2.Text = 'Start';
            app.PauseButton = uibutton(app.Activity1Tab, 'push');
            app.PauseButton.Text = 'Pause';
            app.SaveScoreButton = uibutton(app.Activity1Tab, 'push');
            app.SaveScoreButton.Text = 'Save Score';
            app.TimeLabel = uilabel(app.Activity1Tab);
            app.TimeLabel.Text = '';
            app.TimeNum = uilabel(app.Activity1Tab);
            app.TimeNum.Text = '';
            app.CountLabel = uilabel(app.Activity1Tab);
            app.CountLabel.Text = '';
            app.countNum = uilabel(app.Activity1Tab);
            app.countNum.Text = '';
            app.UsernameLabel = uilabel(app.Activity1Tab);
            app.UsernameLabel.Text = 'Username:';
            app.UsernameFieldLabel = uilabel(app.Activity1Tab);
            app.UsernameFieldLabel.Text = 'UsernameField';
            app.TimestampLabel = uilabel(app.Activity1Tab);
            app.TimestampLabel.Text = 'Timestamp:';
            app.TimestampFieldLabel = uilabel(app.Activity1Tab);
            app.TimestampFieldLabel.Text = 'TimestampField';
            app.StatusLabel = uilabel(app.Activity1Tab);
            app.StatusLabel.Text = 'Status:';
            app.CompleteLabel = uilabel(app.Activity1Tab);
            app.CompleteLabel.Text = 'Complete';
            app.StopButton_2 = uibutton(app.Activity1Tab, 'push');
            app.StopButton_2.Text = 'Stop';
            app.DiscardScoreButton = uibutton(app.Activity1Tab, 'push');
            app.DiscardScoreButton.Text = 'Discard Score';
            app.PlayTutorialButton = uibutton(app.Activity1Tab, 'push');
            app.PlayTutorialButton.ButtonPushedFcn = createCallbackFcn(app, @startTutorial, true);
            app.PlayTutorialButton.Text = 'Play Tutorial';
            app.InstructionsField = uilabel(app.Activity1Tab);
            app.InstructionsField.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Respiration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @preLoad)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end